classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FYPHRRRExtractionUIFigure  matlab.ui.Figure
        RRValue                    matlab.ui.control.Label
        RRLabel                    matlab.ui.control.Label
        HRValue                    matlab.ui.control.Label
        HRLabel                    matlab.ui.control.Label
        ReadingSwitch              matlab.ui.control.Switch
        ReadingSwitchLabel         matlab.ui.control.Label
        InterferometerButtonGroup  matlab.ui.container.ButtonGroup
        MZIButton                  matlab.ui.control.RadioButton
        SagnacButton               matlab.ui.control.RadioButton
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        switchStatus
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadingSwitch
        function ReadingSwitchValueChanged(app, event)
            value = app.ReadingSwitch.Value;
            
            if value == "On"
                app.switchStatus = true;
                app.InterferometerButtonGroup.Enable = 'off';

%                 % Placeholder for the ni daq setting
%                 dq = daq("ni");
%                 if app.SagnacButton.Value
%                     addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
%                 else
%                     addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
%                     addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
%                     addinput(dq, "cDAQ1Mod1", "ai0", "Voltage");
%                 end
%                 dq.Rate = 5000;
% 
%                 % Run daq card
%                 scanData = read(dq,seconds(30));

                loadPVDF = readtable('./Data/Sagnac/Lay_Up_without_anything_Test_1_John.xlsx','Range','B:B');
                PVDFdata = table2array(loadPVDF);
                senRaw = PVDFdata(1:end,1);

                while(app.switchStatus)
                    if app.SagnacButton.Value
                        [HR, RR] = Sagnac_Extraction(senRaw,false);
                        app.HRValue.Text = string(HR);
                        app.RRValue.Text = string(RR);
                        pause(0.1);
                    else
                        app.HRValue.Text = string(200);
                        app.RRValue.Text = string(200);
                    end
                end
                    
                app.HRValue.Text = "-";
                app.RRValue.Text = "-";
            else
                app.switchStatus = false;
                app.InterferometerButtonGroup.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FYPHRRRExtractionUIFigure = uifigure('Visible', 'off');
            app.FYPHRRRExtractionUIFigure.Name = 'FYP HR&RR Extraction';
            app.UIAxes = uiaxes(app.FYPHRRRExtractionUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.FYPHRRRExtractionUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.InterferometerButtonGroup = uibuttongroup(app.FYPHRRRExtractionUIFigure);
            app.InterferometerButtonGroup.Title = 'Interferometer';
            app.SagnacButton = uiradiobutton(app.InterferometerButtonGroup);
            app.SagnacButton.Text = 'Sagnac';
            app.SagnacButton.Value = true;
            app.MZIButton = uiradiobutton(app.InterferometerButtonGroup);
            app.MZIButton.Text = 'MZI';
            app.ReadingSwitchLabel = uilabel(app.FYPHRRRExtractionUIFigure);
            app.ReadingSwitchLabel.Text = 'Reading';
            app.ReadingSwitch = uiswitch(app.FYPHRRRExtractionUIFigure, 'slider');
            app.ReadingSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadingSwitchValueChanged, true);
            app.HRLabel = uilabel(app.FYPHRRRExtractionUIFigure);
            app.HRLabel.Text = 'HR:';
            app.HRValue = uilabel(app.FYPHRRRExtractionUIFigure);
            app.HRValue.Text = '-';
            app.RRLabel = uilabel(app.FYPHRRRExtractionUIFigure);
            app.RRLabel.Text = 'RR:';
            app.RRValue = uilabel(app.FYPHRRRExtractionUIFigure);
            app.RRValue.Text = '-';
            % Show the figure after all components are created
            app.FYPHRRRExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FYPHRRRExtractionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FYPHRRRExtractionUIFigure)
        end
    end
end